classdef nrel_usa_solar_auth < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        SelectTab              matlab.ui.container.Tab
        Image                  matlab.ui.control.Image
        CityNameLabel          matlab.ui.control.Label
        FindButton             matlab.ui.control.Button
        CityEditField          matlab.ui.control.EditField
        CityEditFieldLabel     matlab.ui.control.Label
        LongitudeSlider        matlab.ui.control.Slider
        LongitudeSliderLabel   matlab.ui.control.Label
        LatitudeSlider         matlab.ui.control.Slider
        LatitudeSliderLabel    matlab.ui.control.Label
        RefreshMapButton       matlab.ui.control.Button
        SavePointsButtonGroup  matlab.ui.container.ButtonGroup
        YesButton              matlab.ui.control.RadioButton
        NoButton               matlab.ui.control.RadioButton
        LabelSelectedPoint     matlab.ui.control.Label
        ExportMapButton        matlab.ui.control.Button
        LabelUsaMapDataSrc     matlab.ui.control.Label
        AnalyzeButton          matlab.ui.control.Button
        LongitudeEditField     matlab.ui.control.NumericEditField
        LatitudeEditField      matlab.ui.control.NumericEditField
        SelectPointButton      matlab.ui.control.Button
        UIAxesMap              matlab.ui.control.UIAxes
        AnalyzeTab             matlab.ui.container.Tab
        ExportAllButtonGroup   matlab.ui.container.ButtonGroup
        YesButtonExport        matlab.ui.control.RadioButton
        NoButtonExport         matlab.ui.control.RadioButton
        ExportDataButton       matlab.ui.control.Button
        StateNameLabel         matlab.ui.control.Label
        LabelNrelLink          matlab.ui.control.Label
        UIAxesAnnual           matlab.ui.control.UIAxes
        UIAxesLatTilt          matlab.ui.control.UIAxes
        UIAxesGhi              matlab.ui.control.UIAxes
        UIAxesDni              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        map_data % US county boundaries data
        latitude  % latitude
        longitude % longitude
        months
        % data
        dni_values
        ghi_values
        lat_tilt_values
        avg_annual
        solar_data = struct([]); 
        % interactive point
        selectedPoint  
        selectedPointTxt 
        currentPointData
        pointAdded = false
        % open weather geocoding properties
        state
        county
        apiKeyNREL % NREL API key
        apiKeyOpenWeather % OpenWeather API Key
        % reverse geocoding
        api_call_geocode
        % direct geocoding
        api_call_direct
    end
    
    methods (Access = private)
        
        function  drawMap(app, plot_data, startup)
            data = jsondecode(fileread(plot_data));
            
            continentalStates = {'AL', 'AR', 'AZ', 'CA', 'CO', 'CT', 'DE', 'FL', 'GA', ...
                'IA', 'ID', 'IL', 'IN', 'KS', 'KY', 'LA', 'MA', 'MD', 'ME', 'MI', ...
                'MN', 'MO', 'MS', 'MT', 'NC', 'ND', 'NE', 'NH', 'NJ', 'NM', 'NV', ...
                'NY', 'OH', 'OK', 'OR', 'PA', 'RI', 'SC', 'SD', 'TN', 'TX', 'UT', ...
                'VA', 'VT', 'WA', 'WI', 'WV', 'WY'};
            
            numCounties = length(data);
            latlong = cell(numCounties, 1);
            isContinental = false(numCounties, 1);
            
            for i = 1:numCounties
                if ismember(data(i).stusab, continentalStates)
                    isContinental(i) = true;
                    coords = data(i).st_asgeojson.geometry.coordinates;
                    if isnumeric(coords)
                        if size(coords, 3) == 2  % Handle 1xNx2 arrays
                            latlong{i} = squeeze(coords);
                        else  % Handle Nx2 arrays
                            latlong{i} = coords;
                        end
                    elseif iscell(coords)
                        combinedCoords = [];
                        for j = 1:length(coords)
                            if isnumeric(coords{j}) && ndims(coords{j}) == 3 && size(coords{j}, 3) == 2
                                combinedCoords = [combinedCoords; squeeze(coords{j})];
                            elseif iscell(coords{j})
                                for k = 1:length(coords{j})
                                    if isnumeric(coords{j}{k}) && ndims(coords{j}{k}) == 3 && size(coords{j}{k}, 3) == 2
                                        combinedCoords = [combinedCoords; squeeze(coords{j}{k})];
                                    elseif isnumeric(coords{j}{k}) && size(coords{j}{k}, 2) == 2
                                        combinedCoords = [combinedCoords; coords{j}{k}];
                                    end
                                end
                            elseif isnumeric(coords{j}) && size(coords{j}, 2) == 2
                                combinedCoords = [combinedCoords; coords{j}];
                            end
                        end
                        latlong{i} = combinedCoords;
                    end
                end
            end

            % Filter out non-continental counties
            latlong = latlong(isContinental);
            
            % Plotting the map
            ax = app.UIAxesMap;
            
            for i = 1:length(latlong)
                if ~isempty(latlong{i})
                    patch(ax, latlong{i}(:,1), latlong{i}(:,2), [0.8 0.8 0.8]);
                end
            end

            if strcmp(startup, 'yes')
                hold(ax, 'on');
    
                app.latitude = app.LatitudeEditField.Value;
                app.longitude = app.LongitudeEditField.Value;
                plot(ax, app.longitude, app.latitude, 'ro', 'MarkerSize', 8);

                cities = ["Phoenix", "Los Angeles", "San Francisco", "Las Vegas", "Seattle", "Denver", ...
                    "New York City", "Boston", "Miami", "Atlanta", "Chicago", "Dallas", "Cheyenne", "Portland", "Omaha"];

                for i = 1:length(cities)
                    try
                        % Construct the API call URL
                        api_url_direct = "https://api.openweathermap.org/geo/1.0/direct?q=" + cities(i) + ... 
                            "&limit=1&appid=" + app.apiKeyOpenWeather;
                
                        % Fetch the data from the API
                        app.api_call_direct = webread(api_url_direct);
                
                        % Extract the latitude and longitude of the city
                        lat = app.api_call_direct(1).lat;
                        lon = app.api_call_direct(1).lon;
                
                        % Plot the city as a point on the map
                        plot(ax, lon, lat, 'bo', 'MarkerSize', 6, 'DisplayName', cities(i)); % Blue point for city
                
                        % Add a text label for the city
                        text(ax, lon, lat - 0.2, cities(i), 'FontSize', 8, 'Color', 'blue', 'HorizontalAlignment', 'center', ...
                            'VerticalAlignment', 'top');
                    catch ME
                        % If there's an error (e.g., API limit reached or invalid response), skip this city
                        warning("Could not fetch data for city: %s. Error: %s", cities(i), ME.message);
                    end
                end
               
                hold(ax,'off');

            end

            ax.XLim = [-130,-60]; % Longitude range for the USA
            ax.YLim = [20,55]; % Latitude range for the USA
            ax.XLabel.String = 'Longitude';
            ax.YLabel.String = 'Latitude';
            ax.Title.String = 'Continental USA NREL Solar Data';

        end
        
        function fetchStateName(app)
            % reverse geocode the location         
            api_url_geocode = "https://api.openweathermap.org/geo/1.0/reverse?lat=" + ...
                app.latitude + "&lon=" + app.longitude + "&limit=5&appid=" + app.apiKeyOpenWeather;
            app.api_call_geocode = webread(api_url_geocode);
            % add state and local name label
            if ~isempty(app.api_call_geocode)
                app.StateNameLabel.Text = app.api_call_geocode.state + ", " + app.api_call_geocode.name;
                % app.LabelSelect.Text = api_call_geocode.state + ", " + api_call_geocode.name;
                % add text label
                % check if the point is in Mexico 
                data = jsondecode(fileread(app.map_data));
                if any(strcmp({data.name}, app.api_call_geocode.state))
                    app.selectedPointTxt = text(app.UIAxesMap, app.longitude + 0.5, app.latitude, app.api_call_geocode.state + ", " ...
                        + app.api_call_geocode.name);
                    % add city to search field label
                    app.CityEditField.Value = "";
                    app.CityEditField.Placeholder = "Enter a city name";
                    app.CityNameLabel.Text = "";
                else
                    app.selectedPointTxt = text(app.UIAxesMap, app.longitude + 0.5, app.latitude, "");
                end
                % check if the point is in the ocean
            else 
                app.selectedPointTxt = text(app.UIAxesMap, app.longitude + 0.5, app.latitude, "");
            end
        end
        
        function exportMap(app)
            % Set the path for the image file
            [file, path] = uiputfile( regexprep(app.api_call_geocode.state, ' ', '_') + "_" ...
                    + regexprep(app.api_call_geocode.name, ' ', '_') + ".png", 'Save Map as Image');
            if isequal(file, 0)
                % User canceled the file selection
                return;
            end
                filepath = fullfile(path, file);
                
                % Capture the current state of the UIAxes and save as an image
                exportgraphics(app.UIAxesMap, filepath);
            end
        
        function dataStruct = initDataStruct(app)
            dataStruct = struct();
            dataStruct.location = struct();
            dataStruct.location.latitude = app.latitude;
            dataStruct.location.longitude = app.longitude;
            dataStruct.location.state = app.api_call_geocode.state;
            dataStruct.location.county = app.api_call_geocode.name;
        
            dataStruct.measurement_data = struct();
            dataStruct.measurement_data.months = app.months;
            dataStruct.measurement_data.avg_dni = app.dni_values;
            dataStruct.measurement_data.avg_ghi = app.ghi_values;
            dataStruct.measurement_data.avg_lat_tilt = app.lat_tilt_values;
            dataStruct.measurement_data.avg_annual = app.avg_annual;
        end
        
        function deletePoint(app)
            if ishandle(app.selectedPoint)
                if app.NoButton.Value == 1
                    delete(app.selectedPoint);  % Delete previous point
                end
                delete(app.selectedPointTxt);
            end
        end
        
        function savePoint(app, lat, lon)
            api_url_solar = "https://developer.nrel.gov/api/solar/solar_resource/v1.json?api_key=" ...
                + app.apiKeyNREL  + "&lat=" + lat +  "&lon=" + lon;
            api_call_solar = webread(api_url_solar);
            

            if (strcmp(api_call_solar.outputs.avg_dni,"no data")) || strcmp(api_call_solar.outputs.avg_ghi, "no data") ...
                    || strcmp(api_call_solar.outputs.avg_lat_tilt, "no data")
                    return;
            else
                % Extract avg_dni monthly values
                monthly_dni = api_call_solar.outputs.avg_dni.monthly;
                monthly_ghi = api_call_solar.outputs.avg_ghi.monthly;
                monthly_lat_tilt = api_call_solar.outputs.avg_lat_tilt.monthly;
                
                % Create arrays for months and values
                app.months = {'Jan', 'Feb', 'Mar', 'Apr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec'};
                app.dni_values = [monthly_dni.jan, monthly_dni.feb, monthly_dni.mar, monthly_dni.apr, ...
                              monthly_dni.may, monthly_dni.jun, monthly_dni.jul, monthly_dni.aug, ...
                              monthly_dni.sep, monthly_dni.oct, monthly_dni.nov, monthly_dni.dec];
                app.ghi_values = [monthly_ghi.jan, monthly_ghi.feb, monthly_ghi.mar, monthly_ghi.apr, ...
                              monthly_ghi.may, monthly_ghi.jun, monthly_ghi.jul, monthly_ghi.aug, ...
                              monthly_ghi.sep, monthly_ghi.oct, monthly_ghi.nov, monthly_ghi.dec];
                app.lat_tilt_values = [monthly_lat_tilt.jan, monthly_lat_tilt.feb, monthly_lat_tilt.mar, monthly_lat_tilt.apr, ...
                              monthly_lat_tilt.may, monthly_lat_tilt.jun, monthly_lat_tilt.jul, monthly_lat_tilt.aug, ...
                              monthly_lat_tilt.sep, monthly_lat_tilt.oct, monthly_lat_tilt.nov, monthly_lat_tilt.dec];
                app.avg_annual = [api_call_solar.outputs.avg_dni.annual, api_call_solar.outputs.avg_ghi.annual,...
                    api_call_solar.outputs.avg_lat_tilt.annual];
            end
            % Update currentPointData
            app.currentPointData = initDataStruct(app);
            app.pointAdded = false; % Reset the flag
            
            if app.YesButton.Value == 1
                app.solar_data = [app.solar_data; app.currentPointData]; % Append new data point
                app.pointAdded = true;  % Set the flag to indicate the point has been added
            end
        end
        
        function promptForAPIKeys(app)
            % Create the dialog box
            d = dialog('Position', [300, 300, 350, 200], 'Name', 'Enter API Keys');

            % Create and configure the text fields and labels
            uicontrol('Parent', d, 'Style', 'text', 'Position', [20, 140, 100, 30], 'String', 'NREL API Key:');
            nrelEditField = uicontrol('Parent', d, 'Style', 'edit', 'Position', [120, 145, 200, 25]);

            uicontrol('Parent', d, 'Style', 'text', 'Position', [20, 80, 100, 30], 'String', 'Open Weather API Key:');
            openWeatherEditField = uicontrol('Parent', d, 'Style', 'edit', 'Position', [120, 85, 200, 25]);

            % Create the OK button and configure its callback
            uicontrol('Parent', d, 'Position', [140, 20, 70, 25], 'String', 'OK', 'Callback', @(src, event) okButtonCallback(app, d, nrelEditField, openWeatherEditField, app));
        end

        function okButtonCallback(~, dialog, nrelEditField, openWeatherEditField, app)
            % Get the values from the text fields
            app.apiKeyNREL = nrelEditField.String;
            app.apiKeyOpenWeather = openWeatherEditField.String;

            % Close the dialog
            delete(dialog);
        end

        function capitalizedSentence = capitalizeCityName(~, sentence)
            % Split the sentence into words
            words = split(sentence);
            
            % Capitalize each word
            for i = 1:length(words)
                words{i} = [upper(words{i}(1)), lower(words{i}(2:end))];
            end
            
            % Reassemble the sentence
            capitalizedSentence = strjoin(words, ' ');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.map_data = "us_state_boundaries_rm_islands.json"; % rm irregularities from the map
            % get api keys
            promptForAPIKeys(app);
    
            % Wait for the user to enter the API keys
            waitfor(findall(0, 'Type', 'figure', 'Name', 'Enter API Keys'));
            
            % Check if the API keys are entered, otherwise, show an error
            if isempty(app.apiKeyNREL) || isempty(app.apiKeyOpenWeather)
                uialert(app.UIFigure, 'API keys are required to use this app.', 'Missing API Keys');
                return;
            end

            % set initial values
            app.LongitudeEditField.Value = -100;
            app.LatitudeEditField.Value = 37.5;
            app.LongitudeSlider.Limits = [-130,-60];
            app.LatitudeSlider.Limits = [20,55];
            app.LongitudeSlider.Value = -100;
            app.LatitudeSlider.Value = 37.5;
            % draw the initial map
            drawMap(app, app.map_data,'yes');
            app.LabelUsaMapDataSrc.Text = "USA Map Data: https://public.opendatasoft.com/explore/dataset/us-state-boundaries/information/";
            app.LabelNrelLink.Text = "NREL Resource Data: https://developer.nrel.gov/docs/solar/solar-resource-v1/";
            app.api_call_geocode.state = "Kansas";
            app.api_call_geocode.name = "Ford County";
            app.solar_data = struct([]);
        end

        % Value changed function: LatitudeEditField
        function LatitudeEditFieldValueChanged(app, event)
            value = app.LatitudeEditField.Value;

            if value < 20 || value > 55
                uialert(app.UIFigure, 'Selected point is not within the map bounds.', 'Invalid Point');
                app.LatitudeEditField.Value = app.latitude;
                return;
            else
                app.latitude = value;
                deletePoint(app);

                % Plot the new point
                hold(app.UIAxesMap, 'on');
                app.selectedPoint = plot(app.UIAxesMap, app.longitude, app.latitude, 'ro', 'MarkerFaceColor', 'r');
                fetchStateName(app);

                % save point
                savePoint(app, app.latitude, app.longitude);
            
                % Update edit field
                app.LatitudeSlider.Value = app.latitude;
            end

        end

        % Value changed function: LongitudeEditField
        function LongitudeEditFieldValueChanged(app, event)
            value = app.LongitudeEditField.Value;
            
            if value < -130 || value > -60
                uialert(app.UIFigure, 'Selected point is not within the map bounds.', 'Invalid Point');
                app.LongitudeEditField.Value = app.longitude;
                return;
            else
                app.longitude = value;
                deletePoint(app);

                % Plot the new point
                hold(app.UIAxesMap, 'on');
                app.selectedPoint = plot(app.UIAxesMap, app.longitude, app.latitude, 'ro', 'MarkerFaceColor', 'r');
                fetchStateName(app);

                % save point
                savePoint(app, app.latitude, app.longitude);
            
                % Update edit field
                app.LongitudeSlider.Value = app.longitude;
            end
        end

        % Button pushed function: SelectPointButton
        function SelectPointButtonPushed(app, event)
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
            
            [x,y] = ginput(1);  % Capture one point

            app.UIFigure.HandleVisibility = fhv;        % return to original state

            if x < -130 || x > -60 || y < 20 || y > 55
                 uialert(app.UIFigure, 'Selected point is not within the map bounds.', 'Invalid Point');
                 return;
            end

            deletePoint(app);

            % Update app properties
            app.latitude = y;
            app.longitude = x;

             % Plot the new point
            hold(app.UIAxesMap, 'on');
            app.selectedPoint = plot(app.UIAxesMap, x, y, 'ro', 'MarkerFaceColor', 'r');
            fetchStateName(app);

            % save point
            savePoint(app, app.latitude, app.longitude);

            % Update UI components
            app.LatitudeEditField.Value = y;
            app.LongitudeEditField.Value = x;
            app.LatitudeSlider.Value = y;
            app.LongitudeSlider.Value = x;
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            app.latitude = app.LatitudeEditField.Value;
            app.longitude = app.LongitudeEditField.Value;
            
            api_url_solar = "https://developer.nrel.gov/api/solar/solar_resource/v1.json?api_key=" ...
                + app.apiKeyNREL  + "&lat=" + app.latitude +  "&lon=" + app.longitude;
            api_call_solar = webread(api_url_solar);

            if (strcmp(api_call_solar.outputs.avg_dni,"no data")) || strcmp(api_call_solar.outputs.avg_ghi, "no data") ...
                    || strcmp(api_call_solar.outputs.avg_lat_tilt, "no data")
                      uialert(app.UIFigure, 'No data for the selected coordinates.', 'No Data');
                      deletePoint(app);
                      return;
            else
                % Extract avg_dni monthly values
                monthly_dni = api_call_solar.outputs.avg_dni.monthly;
                monthly_ghi = api_call_solar.outputs.avg_ghi.monthly;
                monthly_lat_tilt = api_call_solar.outputs.avg_lat_tilt.monthly;
                
                % Create arrays for months and values
                app.months = {'Jan', 'Feb', 'Mar', 'Apr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec'};
                app.dni_values = [monthly_dni.jan, monthly_dni.feb, monthly_dni.mar, monthly_dni.apr, ...
                              monthly_dni.may, monthly_dni.jun, monthly_dni.jul, monthly_dni.aug, ...
                              monthly_dni.sep, monthly_dni.oct, monthly_dni.nov, monthly_dni.dec];
                app.ghi_values = [monthly_ghi.jan, monthly_ghi.feb, monthly_ghi.mar, monthly_ghi.apr, ...
                              monthly_ghi.may, monthly_ghi.jun, monthly_ghi.jul, monthly_ghi.aug, ...
                              monthly_ghi.sep, monthly_ghi.oct, monthly_ghi.nov, monthly_ghi.dec];
                app.lat_tilt_values = [monthly_lat_tilt.jan, monthly_lat_tilt.feb, monthly_lat_tilt.mar, monthly_lat_tilt.apr, ...
                              monthly_lat_tilt.may, monthly_lat_tilt.jun, monthly_lat_tilt.jul, monthly_lat_tilt.aug, ...
                              monthly_lat_tilt.sep, monthly_lat_tilt.oct, monthly_lat_tilt.nov, monthly_lat_tilt.dec];

                % save the data
                if app.NoButton.Value == 1
                    newDataPoint = initDataStruct(app);
                    app.solar_data = [app.solar_data; newDataPoint]; 
                end 

                app.TabGroup.SelectedTab = app.AnalyzeTab;
                
                % Plot the data
                ax_dni = app.UIAxesDni;
                plot(ax_dni, app.dni_values, '-o');
                ax_dni.XTick = 1:12;
                ax_dni.XTickLabel = app.months;
                ax_dni.XLabel.String = 'Month';
                ax_dni.YLabel.String = 'Average DNI (kWh/m^2/day)';
                ax_dni.Title.String = 'Monthly Average DNI';
                hold(ax_dni, 'on');
                % plot the avg line
                plot(ax_dni, api_call_solar.outputs.avg_dni.annual*ones(size(app.dni_values)), '--r');
                text(ax_dni,6, api_call_solar.outputs.avg_dni.annual - 0.3, "avg: " + ...
                    num2str(api_call_solar.outputs.avg_dni.annual));
                hold(ax_dni, 'off');
                
                ax_ghi = app.UIAxesGhi;
                plot(ax_ghi, app.ghi_values, '-o');
                ax_ghi.XTick = 1:12;
                ax_ghi.XTickLabel = app.months;
                ax_ghi.XLabel.String = 'Month';
                ax_ghi.YLabel.String = 'Average GHI (kWh/m^2/day)';
                ax_ghi.Title.String = 'Monthly Average GHI';
                hold(ax_ghi, 'on');
                % plot the avg line
                plot(ax_ghi, api_call_solar.outputs.avg_ghi.annual*ones(size(app.ghi_values)), '--r');
                text(ax_ghi,6, api_call_solar.outputs.avg_ghi.annual - 0.3, "avg: " + ...
                    num2str(api_call_solar.outputs.avg_ghi.annual));
                hold(ax_ghi, 'off');
                
                ax_lat_tilt = app.UIAxesLatTilt;
                plot(ax_lat_tilt, app.lat_tilt_values, '-o');
                ax_lat_tilt.XTick = 1:12;
                ax_lat_tilt.XTickLabel = app.months;
                ax_lat_tilt.XLabel.String = 'Month';
                ax_lat_tilt.YLabel.String = 'Average LAT TILT (kWh/m^2/day)';
                ax_lat_tilt.Title.String = 'Monthly Average LAT TILT';
                hold(ax_lat_tilt, 'on');
                % plot the avg line
                plot(ax_lat_tilt, api_call_solar.outputs.avg_lat_tilt.annual*ones(size(app.lat_tilt_values)), '--r');
                text(ax_lat_tilt,6, api_call_solar.outputs.avg_lat_tilt.annual - 0.3, "avg: " + ...
                    num2str(api_call_solar.outputs.avg_lat_tilt.annual));
                hold(ax_lat_tilt, 'off');
                    
                ax_avg_annual = app.UIAxesAnnual;
                measurement = {'Avg DNI','Avg GHI','Avg LAT TILT'};
                app.avg_annual = [api_call_solar.outputs.avg_dni.annual, api_call_solar.outputs.avg_ghi.annual,...
                    api_call_solar.outputs.avg_lat_tilt.annual];
                bar(ax_avg_annual,app.avg_annual);
                ax_avg_annual.XTick = 1:3;
                ax_avg_annual.XTickLabel = measurement;
                ax_avg_annual.XLabel.String = 'Measurements';
                ax_avg_annual.YLabel.String = 'Average Annual (kWh/m^2/day)';
                ax_avg_annual.Title.String = 'Annual Average Solar Irradiance';
                hold(ax_avg_annual, 'on');
                % plot the avg line
                plot(ax_avg_annual, mean(app.avg_annual)*ones(size(app.avg_annual)), '--r', LineWidth=2);
                hold(ax_avg_annual, 'off');
            end
        end

        % Value changed function: LatitudeSlider
        function LatitudeSliderValueChanged(app, event)
            value = app.LatitudeSlider.Value;
            app.latitude = value;
            deletePoint(app);

            % Plot the new point
            hold(app.UIAxesMap, 'on');
            app.selectedPoint = plot(app.UIAxesMap, app.longitude, app.latitude, 'ro', 'MarkerFaceColor', 'r');
            fetchStateName(app);

            % save point
            savePoint(app, app.latitude, app.longitude);
        
            % Update edit field
            app.LatitudeEditField.Value = app.latitude;
        end

        % Value changed function: LongitudeSlider
        function LongitudeSliderValueChanged(app, event)
            value = app.LongitudeSlider.Value;
            app.longitude = value;
            deletePoint(app);

            % Plot the new point
            hold(app.UIAxesMap, 'on');
            app.selectedPoint = plot(app.UIAxesMap, app.longitude, app.latitude, 'ro', 'MarkerFaceColor', 'r');
            fetchStateName(app);

            % save point
            savePoint(app, app.latitude, app.longitude);
        
            % Update edit field
            app.LongitudeEditField.Value = app.longitude;
        end

        % Value changing function: LatitudeSlider
        function LatitudeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.LatitudeEditField.Value = changingValue;
        end

        % Value changing function: LongitudeSlider
        function LongitudeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.LongitudeEditField.Value = changingValue;
        end

        % Button pushed function: ExportMapButton
        function ExportMapButtonPushed(app, event)
            exportMap(app);
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            if isempty(app.solar_data)
                uialert(app.UIFigure, 'No data to export.', 'Export Data');
                return;
            end

             % Convert the solar_data structure array to JSON format

             % timestamp for unique file name
             timestamp = datestr(now, 'yyyymmdd_HHMMSS');
             if app.YesButtonExport.Value == 1
                 [file, path] = uiputfile("solar_data_" + timestamp + ".json", 'Save Data as JSON');
                  jsonData = jsonencode(app.solar_data);
             else
                 [file, path] = uiputfile(regexprep(app.api_call_geocode.state, ' ', '_') + "_" ...
                    + regexprep(app.api_call_geocode.name, ' ', '_') + ".json", 'Save Data as JSON');
                 jsonData = jsonencode(app.solar_data(end));
             end

            if isequal(file, 0)
                % User canceled the file selection
                return;
            end
            filepath = fullfile(path, file);
            
            % Save the JSON data to the file
            fid = fopen(filepath, 'w');
            if fid == -1
                error('Cannot create JSON file.');
            end
            fwrite(fid, jsonData, 'char');
            fclose(fid);
        end

        % Button pushed function: RefreshMapButton
        function RefreshMapButtonPushed(app, event)
            deletePoint(app);
            app.LongitudeEditField.Value = -100;
            app.LatitudeEditField.Value = 37.5;
            app.LongitudeSlider.Value = -100;
            app.LatitudeSlider.Value = 37.5;
            app.StateNameLabel.Text = "Kansas, Ford County";
            % reset search field
            app.CityEditField.Value = "";
            app.CityEditField.Placeholder = "Enter a city name";
            app.CityNameLabel.Text = "";
            % draw initial map
            drawMap(app, app.map_data,'yes');
            cla(app.UIAxesDni);
            cla(app.UIAxesGhi);
            cla(app.UIAxesLatTilt);
            cla(app.UIAxesAnnual);
            app.solar_data = struct([]); % Reset the solar data
        end

        % Selection changed function: SavePointsButtonGroup
        function SavePointsButtonGroupSelectionChanged(app, event)
            if app.YesButton.Value == 1 && ~isempty(app.currentPointData) && ~app.pointAdded
                % Append the current point's data to solar_data
                app.solar_data = [app.solar_data; app.currentPointData];
                app.pointAdded = true;  % Set the flag to indicate the point has been added
            elseif app.NoButton.Value == 1
                % If changing back to 'No', ensure no duplicate entry is added
                app.pointAdded = false;
            end
        end

        % Button pushed function: FindButton
        function FindButtonPushed(app, event)
            deletePoint(app);
            % Get the city name from the text field
            cityName = app.CityEditField.Value;
            % Find latitude and longitude
            api_url_direct = "https://api.openweathermap.org/geo/1.0/direct?q=" + cityName + ... 
                            "&limit=1&appid=" + app.apiKeyOpenWeather;   
            % Fetch the data from the API
            app.api_call_direct = webread(api_url_direct);

            % Check if the call is empty
            if ~isempty(app.api_call_direct)
                % Check if the city is in the US
                if app.api_call_direct(1).country ~= "US"
                    app.CityNameLabel.Text = "Unknown city.";
                else
                    % Extract the latitude and longitude of the city
                    lat = app.api_call_direct(1).lat;
                    lon = app.api_call_direct(1).lon;
    
                    % Update app properties
                    app.latitude = lat;
                    app.longitude = lon;
                    
                    % Plot the new point
                    hold(app.UIAxesMap, 'on');
                    app.selectedPoint = plot(app.UIAxesMap, lon, lat, 'ro', 'MarkerFaceColor', 'r');
                    % Find State, County
                    fetchStateName(app);
                    
                    % save point
                    savePoint(app, app.latitude, app.longitude);
                    
                    % Update UI components
                    app.LatitudeEditField.Value = lat;
                    app.LongitudeEditField.Value = lon;
                    app.LatitudeSlider.Value = lat;
                    app.LongitudeSlider.Value = lon;
                    
                    app.CityNameLabel.Text = capitalizeCityName(app,cityName);
                end
            else
                app.CityNameLabel.Text = "Unknown city.";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SelectTab = uitab(app.TabGroup);
            app.SelectTab.Title = 'Select';
            app.UIAxesMap = uiaxes(app.SelectTab);
            title(app.UIAxesMap, 'Title')
            xlabel(app.UIAxesMap, 'X')
            ylabel(app.UIAxesMap, 'Y')
            zlabel(app.UIAxesMap, 'Z')
            app.UIAxesMap.XLim = [-130 -60];
            app.UIAxesMap.YLim = [20 55];
            app.SelectPointButton = uibutton(app.SelectTab, 'push');
            app.SelectPointButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPointButtonPushed, true);
            app.SelectPointButton.Text = 'Select Point';
            app.LatitudeEditField = uieditfield(app.SelectTab, 'numeric');
            app.LatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LatitudeEditFieldValueChanged, true);
            app.LongitudeEditField = uieditfield(app.SelectTab, 'numeric');
            app.LongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LongitudeEditFieldValueChanged, true);
            app.AnalyzeButton = uibutton(app.SelectTab, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.LabelUsaMapDataSrc = uilabel(app.SelectTab);
            app.LabelUsaMapDataSrc.Text = 'URL';
            app.ExportMapButton = uibutton(app.SelectTab, 'push');
            app.ExportMapButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMapButtonPushed, true);
            app.ExportMapButton.Text = 'Export Map';
            app.LabelSelectedPoint = uilabel(app.SelectTab);
            app.LabelSelectedPoint.Text = '';
            app.SavePointsButtonGroup = uibuttongroup(app.SelectTab);
            app.SavePointsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SavePointsButtonGroupSelectionChanged, true);
            app.SavePointsButtonGroup.Title = 'Save Points';
            app.NoButton = uiradiobutton(app.SavePointsButtonGroup);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.YesButton = uiradiobutton(app.SavePointsButtonGroup);
            app.YesButton.Text = 'Yes';
            app.RefreshMapButton = uibutton(app.SelectTab, 'push');
            app.RefreshMapButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshMapButtonPushed, true);
            app.RefreshMapButton.Text = 'Refresh Map';
            app.LatitudeSliderLabel = uilabel(app.SelectTab);
            app.LatitudeSliderLabel.Text = 'Latitude';
            app.LatitudeSlider = uislider(app.SelectTab);
            app.LatitudeSlider.ValueChangedFcn = createCallbackFcn(app, @LatitudeSliderValueChanged, true);
            app.LatitudeSlider.ValueChangingFcn = createCallbackFcn(app, @LatitudeSliderValueChanging, true);
            app.LongitudeSliderLabel = uilabel(app.SelectTab);
            app.LongitudeSliderLabel.Text = 'Longitude';
            app.LongitudeSlider = uislider(app.SelectTab);
            app.LongitudeSlider.ValueChangedFcn = createCallbackFcn(app, @LongitudeSliderValueChanged, true);
            app.LongitudeSlider.ValueChangingFcn = createCallbackFcn(app, @LongitudeSliderValueChanging, true);
            app.CityEditFieldLabel = uilabel(app.SelectTab);
            app.CityEditFieldLabel.Text = 'City';
            app.CityEditField = uieditfield(app.SelectTab, 'text');
            app.CityEditField.Placeholder = 'Enter a city name';
            app.FindButton = uibutton(app.SelectTab, 'push');
            app.FindButton.ButtonPushedFcn = createCallbackFcn(app, @FindButtonPushed, true);
            app.FindButton.Text = 'Find';
            app.CityNameLabel = uilabel(app.SelectTab);
            app.CityNameLabel.Text = '';
            app.Image = uiimage(app.SelectTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'sun-grey.png');
            app.AnalyzeTab = uitab(app.TabGroup);
            app.AnalyzeTab.Title = 'Analyze';
            app.UIAxesDni = uiaxes(app.AnalyzeTab);
            title(app.UIAxesDni, 'Title')
            xlabel(app.UIAxesDni, 'X')
            ylabel(app.UIAxesDni, 'Y')
            zlabel(app.UIAxesDni, 'Z')
            app.UIAxesGhi = uiaxes(app.AnalyzeTab);
            title(app.UIAxesGhi, 'Title')
            xlabel(app.UIAxesGhi, 'X')
            ylabel(app.UIAxesGhi, 'Y')
            zlabel(app.UIAxesGhi, 'Z')
            app.UIAxesLatTilt = uiaxes(app.AnalyzeTab);
            title(app.UIAxesLatTilt, 'Title')
            xlabel(app.UIAxesLatTilt, 'X')
            ylabel(app.UIAxesLatTilt, 'Y')
            zlabel(app.UIAxesLatTilt, 'Z')
            app.UIAxesAnnual = uiaxes(app.AnalyzeTab);
            title(app.UIAxesAnnual, 'Title')
            xlabel(app.UIAxesAnnual, 'X')
            ylabel(app.UIAxesAnnual, 'Y')
            zlabel(app.UIAxesAnnual, 'Z')
            app.LabelNrelLink = uilabel(app.AnalyzeTab);
            app.LabelNrelLink.Text = 'URL';
            app.StateNameLabel = uilabel(app.AnalyzeTab);
            app.StateNameLabel.Text = 'State, Name';
            app.ExportDataButton = uibutton(app.AnalyzeTab, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.ExportAllButtonGroup = uibuttongroup(app.AnalyzeTab);
            app.ExportAllButtonGroup.Title = 'Export All';
            app.NoButtonExport = uiradiobutton(app.ExportAllButtonGroup);
            app.NoButtonExport.Text = 'No';
            app.NoButtonExport.Value = true;
            app.YesButtonExport = uiradiobutton(app.ExportAllButtonGroup);
            app.YesButtonExport.Text = 'Yes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nrel_usa_solar_auth

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end